classdef SAR0105 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        InputParameterPanel          matlab.ui.container.Panel
        PulsewidthEditFieldLabel     matlab.ui.control.Label
        PulsewidthEditField          matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        DroneHeightEditField         matlab.ui.control.NumericEditField
        Label_2                      matlab.ui.control.Label
        DroneRadEditField            matlab.ui.control.NumericEditField
        nsLabel                      matlab.ui.control.Label
        kmLabel                      matlab.ui.control.Label
        radLabel                     matlab.ui.control.Label
        GHzLabel                     matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        FcEditField                  matlab.ui.control.NumericEditField
        Label_5                      matlab.ui.control.Label
        DroneVEditField              matlab.ui.control.NumericEditField
        msLabel                      matlab.ui.control.Label
        LLabel                       matlab.ui.control.Label
        LEditField                   matlab.ui.control.NumericEditField
        WEditFieldLabel              matlab.ui.control.Label
        WEditField                   matlab.ui.control.NumericEditField
        Label_6                      matlab.ui.control.Label
        EfficiencyEditField          matlab.ui.control.NumericEditField
        mazimuthLabel                matlab.ui.control.Label
        melevationLabel              matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        chirpgainEditFieldLabel      matlab.ui.control.Label
        chirpgainEditField           matlab.ui.control.NumericEditField
        chirpfoEditFieldLabel        matlab.ui.control.Label
        chirpfoEditField             matlab.ui.control.NumericEditField
        chirpdeltatauEditFieldLabel  matlab.ui.control.Label
        chirpdeltatauEditField       matlab.ui.control.NumericEditField
        chirpmuEditFieldLabel        matlab.ui.control.Label
        chirpmuEditField             matlab.ui.control.NumericEditField
        nsLabel_2                    matlab.ui.control.Label
        StartButton                  matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        OutputParameterPanel         matlab.ui.container.Panel
        mLabel_5                     matlab.ui.control.Label
        m2Label                      matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        dBLabel                      matlab.ui.control.Label
        MHzLabel                     matlab.ui.control.Label
        mLabel                       matlab.ui.control.Label
        mLabel_2                     matlab.ui.control.Label
        kmLabel_2                    matlab.ui.control.Label
        EditField_4Label             matlab.ui.control.Label
        LambdaEditField              matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        AeEditField                  matlab.ui.control.NumericEditField
        azEditFieldLabel             matlab.ui.control.Label
        azEditField                  matlab.ui.control.NumericEditField
        elEditFieldLabel             matlab.ui.control.Label
        elEditField                  matlab.ui.control.NumericEditField
        Label_10                     matlab.ui.control.Label
        GainEditField                matlab.ui.control.NumericEditField
        EditField_4Label_2           matlab.ui.control.Label
        GaindBEditField              matlab.ui.control.NumericEditField
        BandwidthEditFieldLabel      matlab.ui.control.Label
        BandwidthEditField           matlab.ui.control.NumericEditField
        Label_11                     matlab.ui.control.Label
        RangeResolutionEditField     matlab.ui.control.NumericEditField
        EditField_6Label             matlab.ui.control.Label
        AzimuthResolutionEditField   matlab.ui.control.NumericEditField
        Label_12                     matlab.ui.control.Label
        RangeEditField               matlab.ui.control.NumericEditField
        PRIminEditFieldLabel         matlab.ui.control.Label
        PRIminEditField              matlab.ui.control.NumericEditField
        PRFminEditFieldLabel         matlab.ui.control.Label
        PRFminEditField              matlab.ui.control.NumericEditField
        Label_13                     matlab.ui.control.Label
        BeamRangeEditField           matlab.ui.control.NumericEditField
        mLabel_4                     matlab.ui.control.Label
        msLabel_2                    matlab.ui.control.Label
        HzLabel                      matlab.ui.control.Label
        beamUIAxes                   matlab.ui.control.UIAxes
        correlationUIAxes            matlab.ui.control.UIAxes
        inputGround2dUIAxes          matlab.ui.control.UIAxes
        inputGround3dUIAxes          matlab.ui.control.UIAxes
        outputGround2dUIAxes         matlab.ui.control.UIAxes
        outputGround3dUIAxes         matlab.ui.control.UIAxes
        OutputPanel                  matlab.ui.container.Panel
        Label_15                     matlab.ui.control.Label
        StandardTimeEditField        matlab.ui.control.NumericEditField
        usLabel                      matlab.ui.control.Label
        Label_14                     matlab.ui.control.Label
        ErrorrateEditFieldLabel      matlab.ui.control.Label
        ErrorrateEditField           matlab.ui.control.NumericEditField
        Label_16                     matlab.ui.control.Label
        DronetimeField               matlab.ui.control.NumericEditField
        sLabel                       matlab.ui.control.Label
        signalUIAxes                 matlab.ui.control.UIAxes
        GaussianNoisePanel           matlab.ui.container.Panel
        NoiseSDEditField             matlab.ui.control.NumericEditField
        SwitchKnobLabel              matlab.ui.control.Label
        NoiseSwitchKnob              matlab.ui.control.DiscreteKnob
        StandardDeviationKnobLabel   matlab.ui.control.Label
        NoiseSwitchEditField         matlab.ui.control.EditField
        NoiseStandardDeviationKnob   matlab.ui.control.Knob
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% *********************************** Default *********************************
            % input parameter
            c = 3*10^8;
            app.PulsewidthEditField.Value = 10;
            pulsewidth = app.PulsewidthEditField.Value;
            dt = pulsewidth*10^-3;
            app.DroneHeightEditField.Value = 1;
            h = app.DroneHeightEditField.Value;
            app.DroneRadEditField.Value = 10;
            gamma = app.DroneRadEditField.Value;
            app.FcEditField.Value = 9;  % < L-band > frequency : 1~2[GHz], wavelength : 15~30[cm]
            fc = app.FcEditField.Value; % < X-band > frequency : 7.5~12[GHz], wavelength : 2.5~4[cm]
            app.DroneVEditField.Value = 30;
            v = app.DroneVEditField.Value; 
            app.LEditField.Value = 1;
            L = app.LEditField.Value;
            app.WEditField.Value = 1;
            W = app.WEditField.Value;
            app.EfficiencyEditField.Value = 70;
            eta = app.EfficiencyEditField.Value;
            
            app.chirpgainEditField.Value = 1;
            chirp_gain = app.chirpgainEditField.Value;
            app.chirpfoEditField.Value = 1;
            chirp_fo = app.chirpfoEditField.Value;
            app.chirpmuEditField.Value = 1;
            chirp_mu = app.chirpmuEditField.Value;
            app.chirpdeltatauEditField.Value = 0.5;
            chirp_delta_tau = app.chirpdeltatauEditField.Value;
            
            % output parameter
            app.LambdaEditField.Value = c/(fc*10^9);
            lambda = app.LambdaEditField.Value;
            app.AeEditField.Value = L*W*0.01;
            Ae = app.AeEditField.Value;
            app.azEditField.Value = 0.886*(lambda/L)*10^2;
            theta_az = app.azEditField.Value;
            app.elEditField.Value = 0.886*(lambda/W)*10^2;
            theta_el = app.elEditField.Value;
            app.BandwidthEditField.Value = (1/pulsewidth)*10^3;
            Bandwidth = app.BandwidthEditField.Value;
            app.RangeResolutionEditField.Value = c/(2*Bandwidth*10^6);
            delta_r = app.RangeResolutionEditField.Value;
            app.AzimuthResolutionEditField.Value = L/2;
            delta_az = app.AzimuthResolutionEditField.Value;
            
            app.RangeEditField.Value = h/cos(deg2rad(gamma));
            R = app.RangeEditField.Value;
            app.PRFminEditField.Value = (2*v*sin(deg2rad(theta_az))*fc*10^9)/c;
            PRFmin = app.PRFminEditField.Value;
            app.PRIminEditField.Value = 1/PRFmin*10^3;
            PRImin = app.PRIminEditField.Value;
            app.GainEditField.Value = 4*pi*Ae*(eta*10^-2)/lambda^2;
            A = app.GainEditField.Value;
            app.GaindBEditField.Value = 10*log10(A);
            A_dB = app.GaindBEditField.Value;
            
            length_az = R/cos(deg2rad(theta_az));
            length_el = R/cos(deg2rad(theta_el));
            BeamWidth_xlength = 2*length_az*sin(deg2rad(theta_az));  % x축[m]
            BeamWidth_ylength = 2*length_el*sin(deg2rad(theta_el));  % y축[m]
            app.BeamRangeEditField.Value = BeamWidth_xlength*10^3;
                      
            % Gausian Noise
            app.NoiseSwitchEditField.Value = 'Off';
            app.NoiseSwitchKnob.Value = 'Off';
            app.NoiseSDEditField.Value = 1;
            app.NoiseStandardDeviationKnob.Value = 1;
            noiseSD = app.NoiseSDEditField.Value;
            if isequal(app.NoiseSwitchKnob.Value,'Off')
                noise = 0;
            elseif isequal(app.NoiseSwitchKnob.Value,'On')
                noise = 1;
            end
            
            % 지표에 비치는 빔의 길이
            theta = linspace(0, 2*pi, 100);
            x_circle = 1/2*BeamWidth_xlength*10^3*cos(theta);
            y_circle = 1/2*BeamWidth_ylength*10^3*sin(theta);
            z_circle = ones(1,100);
            plot(app.beamUIAxes,x_circle,y_circle);
            axis(app.beamUIAxes,'square');
            
            % signal
            t = 0 : dt : pulsewidth;      % 0 : 0.01 : 10 [ns]
            N = length(t);
            st1 = chirp_gain.*cos(2*pi.*(1*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st2 = chirp_gain.*cos(2*pi.*(2*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st3 = chirp_gain.*cos(2*pi.*(3*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st4 = chirp_gain.*cos(2*pi.*(chirp_fo*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st5 = chirp_gain.*cos(2*pi.*(5*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st6 = chirp_gain.*cos(2*pi.*(6*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st7 = chirp_gain.*cos(2*pi.*(7*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st8 = chirp_gain.*cos(2*pi.*(8*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            
            rt1 = [ zeros(1,N), st1, zeros(1,N) ];
            rt2 = [ zeros(1,N+10), st2, zeros(1,N-10) ];
            rt3 = [ zeros(1,N+20), st3, zeros(1,N-20) ];
            rt4 = [ zeros(1,N+30), st4, zeros(1,N-30) ];
            rt5 = [ zeros(1,N+40), st5, zeros(1,N-40) ];
            rt6 = [ zeros(1,N+50), st6, zeros(1,N-50) ];
            rt7 = [ zeros(1,N+60), st7, zeros(1,N-60) ];
            rt8 = [ zeros(1,N+70), st8, zeros(1,N-70) ];
            
            rt = (rt4+rt4+rt5+rt6+rt6+rt8+rt8+rt5+rt5+rt2+rt1+rt1+rt3+rt4+rt5)+randn(1,length(rt4+rt4+rt5+rt6+rt6+rt8+rt8+rt5+rt5+rt2+rt1+rt1+rt3+rt4+rt5))*noise*noiseSD;
            rt_matrix = [rt4, rt4, rt5, rt6, rt6, rt8, rt8, rt5, rt5, rt2, rt1, rt1, rt3, rt4, rt5];
            rt_matrix = rt_matrix + randn(1,length(rt_matrix))*noise*noiseSD;
            plot(app.signalUIAxes,(0:45*length(t)-1)*dt,rt_matrix);
            
            rt_1 = (rt4 + rt1) + randn(1,length(rt4 + rt1)) * noise * noiseSD;
            rt_2 = (rt4 + rt2) + randn(1,length(rt4 + rt2)) * noise * noiseSD;
            rt_3 = (rt4 + rt3) + randn(1,length(rt4 + rt3)) * noise * noiseSD;
            rt_4 = (rt4 + rt4) + randn(1,length(rt4 + rt4)) * noise * noiseSD;
            rt_5 = (rt4 + rt5) + randn(1,length(rt4 + rt5)) * noise * noiseSD;
            rt_6 = (rt4 + rt6) + randn(1,length(rt4 + rt6)) * noise * noiseSD;
            rt_7 = (rt4 + rt7) + randn(1,length(rt4 + rt7)) * noise * noiseSD;
            rt_8 = (rt4 + rt8) + randn(1,length(rt4 + rt8)) * noise * noiseSD;
 
            ct = length(rt)-N;
                for a = 0:ct
                    c_t4(a+1) = rt_4(a+1:a+N)*(st4+st4)';
                    c_t5(a+1) = rt_5(a+1:a+N)*(st5+st4)';
                    c_t6(a+1) = rt_6(a+1:a+N)*(st6+st4)';
                    c_t8(a+1) = rt_8(a+1:a+N)*(st8+st4)';
                    c_t1(a+1) = rt_1(a+1:a+N)*(st1+st4)';
                    c_t2(a+1) = rt_2(a+1:a+N)*(st2+st4)';
                    c_t3(a+1) = rt_3(a+1:a+N)*(st3+st4)';
                end
                total_c_t = [c_t4, c_t4, c_t5, c_t6, c_t6, c_t8, c_t8, c_t5, c_t5, c_t2, c_t1, c_t1, c_t3, c_t4, c_t5];

            plot(app.correlationUIAxes,(0:length(total_c_t)-1), abs(total_c_t));

            % 2차원 측면 input data, length : 30
            ground = [0 0 0 0 1 1 2 2 2 2 4 4 4 4 1 1 1 1 -2 -2 -3 -3 -3 -3 -1 -1 -1 0 0 1 1];
            x = 0 : pulsewidth : 300;
            plot(app.inputGround2dUIAxes,x,ground);
            xlabel(app.inputGround2dUIAxes,"distance[m]");
            ylabel(app.inputGround2dUIAxes,"height[m]");
            theta_ground = linspace(0, 2*pi, 1/2*app.BeamRangeEditField.Value);
            beamwidth_ground = 1/2*BeamWidth_xlength*cos(theta_ground);
            hold(app.inputGround2dUIAxes);
            plot(app.inputGround2dUIAxes,round(beamwidth_ground*10^-1),'r','LineWidth',2);
            legend(app.inputGround2dUIAxes,'input data','beam width');
            
            % 3차원 input data
            y = 0 : pulsewidth : 300;
            ground_3d = [ground].*[ground]';
            mesh(app.inputGround3dUIAxes,x,y,ground_3d);
            xlabel(app.inputGround3dUIAxes,"distance[m]");
            ylabel(app.inputGround3dUIAxes,"distance[m]");
            zlabel(app.inputGround3dUIAxes,"height[m]");
            hold(app.inputGround3dUIAxes);
            plot3(app.inputGround3dUIAxes,x_circle,y_circle,z_circle,'r','LineWidth',2);

            % output
            app.StandardTimeEditField.Value = (2*R*10^9)/c;
            app.DronetimeField.Value = 300/v;
            
            % 거리측정 
            new_ground = R + ground;                    % [m]
            time = (2*new_ground/c)*10^9;               % [ns]
            time_with_noise = time + randn(1,length(x))*noise*noiseSD;
            R_with_noise = (time_with_noise*c/2)*10^-9;
            ground_with_noise = R_with_noise - R;
            final_ground = round(ground_with_noise);    % 최종 ground input data
            final_ground_3d = final_ground .* final_ground';
            
            plot(app.outputGround2dUIAxes,x,final_ground);
            mesh(app.outputGround3dUIAxes,x,y,final_ground_3d);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
% *********************************** Start button *********************************
            % input parameter
            c = 3*10^8;
            pulsewidth = app.PulsewidthEditField.Value;
            dt = pulsewidth*10^-3;
            h = app.DroneHeightEditField.Value;
            gamma = app.DroneRadEditField.Value;
                                        % < L-band > frequency : 1~2[GHz], wavelength : 15~30[cm]
            fc = app.FcEditField.Value; % < X-band > frequency : 7.5~12[GHz], wavelength : 2.5~4[cm]
            v = app.DroneVEditField.Value;            
            L = app.LEditField.Value;
            W = app.WEditField.Value;
            eta = app.EfficiencyEditField.Value;
            
            chirp_gain = app.chirpgainEditField.Value;
            chirp_fo = app.chirpfoEditField.Value;
            chirp_mu = app.chirpmuEditField.Value;
            chirp_delta_tau = app.chirpdeltatauEditField.Value;
            
            % output parameter
            app.LambdaEditField.Value = c/(fc*10^9);
            lambda = app.LambdaEditField.Value;
            app.AeEditField.Value = L*W*0.01;
            Ae = app.AeEditField.Value;
            app.azEditField.Value = 0.886*(lambda/L)*10^2;
            theta_az = app.azEditField.Value;
            app.elEditField.Value = 0.886*(lambda/W)*10^2;
            theta_el = app.elEditField.Value;
            app.BandwidthEditField.Value = (1/pulsewidth)*10^3;
            Bandwidth = app.BandwidthEditField.Value;
            app.RangeResolutionEditField.Value = c/(2*Bandwidth*10^6);
            delta_r = app.RangeResolutionEditField.Value;
            app.AzimuthResolutionEditField.Value = L/2;
            delta_az = app.AzimuthResolutionEditField.Value;
            
            app.RangeEditField.Value = h/cos(deg2rad(gamma));
            R = app.RangeEditField.Value;
            app.PRFminEditField.Value = (2*v*sin(deg2rad(theta_az))*fc*10^9)/c;
            PRFmin = app.PRFminEditField.Value;
            app.PRIminEditField.Value = 1/PRFmin*10^3;
            PRImin = app.PRIminEditField.Value;
            app.GainEditField.Value = 4*pi*Ae*(eta*10^-2)/lambda^2;
            A = app.GainEditField.Value;
            app.GaindBEditField.Value = 10*log10(A);
            A_dB = app.GaindBEditField.Value;
            
            length_az = R/cos(deg2rad(theta_az));
            length_el = R/cos(deg2rad(theta_el));
            BeamWidth_xlength = 2*length_az*sin(deg2rad(theta_az));  % x축[m]
            BeamWidth_ylength = 2*length_el*sin(deg2rad(theta_el));  % y축[m]
            app.BeamRangeEditField.Value = BeamWidth_xlength*10^3;
            
            % Gausian Noise
            noiseSD = app.NoiseSDEditField.Value;
            if isequal(app.NoiseSwitchKnob.Value,'Off')
                noise = 0;
            elseif isequal(app.NoiseSwitchKnob.Value,'On')
                noise = 1;
            end
            
            % 지표에 비치는 빔의 길이
            theta = linspace(0, 2*pi, 100);
            x_circle = 1/2*BeamWidth_xlength*10^3*cos(theta);
            y_circle = 1/2*BeamWidth_ylength*10^3*sin(theta);
            z_circle = ones(1,100);
            plot(app.beamUIAxes,x_circle,y_circle);
            axis(app.beamUIAxes,'square');
            
            % signal
            t = 0 : dt : pulsewidth;      % 0 : 0.01 : 10 [ns]
            N = length(t);
            st1 = chirp_gain.*cos(2*pi.*(1*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st2 = chirp_gain.*cos(2*pi.*(2*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st3 = chirp_gain.*cos(2*pi.*(3*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st4 = chirp_gain.*cos(2*pi.*(chirp_fo*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st5 = chirp_gain.*cos(2*pi.*(5*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st6 = chirp_gain.*cos(2*pi.*(6*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st7 = chirp_gain.*cos(2*pi.*(7*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st8 = chirp_gain.*cos(2*pi.*(8*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            
            rt1 = [ zeros(1,N), st1, zeros(1,N) ];
            rt2 = [ zeros(1,N+10), st2, zeros(1,N-10) ];
            rt3 = [ zeros(1,N+20), st3, zeros(1,N-20) ];
            rt4 = [ zeros(1,N+30), st4, zeros(1,N-30) ];
            rt5 = [ zeros(1,N+40), st5, zeros(1,N-40) ];
            rt6 = [ zeros(1,N+50), st6, zeros(1,N-50) ];
            rt7 = [ zeros(1,N+60), st7, zeros(1,N-60) ];
            rt8 = [ zeros(1,N+70), st8, zeros(1,N-70) ];
            
            rt = (rt4+rt4+rt5+rt6+rt6+rt8+rt8+rt5+rt5+rt2+rt1+rt1+rt3+rt4+rt5)+randn(1,length(rt4+rt4+rt5+rt6+rt6+rt8+rt8+rt5+rt5+rt2+rt1+rt1+rt3+rt4+rt5))*noise*noiseSD;
            rt_matrix = [rt4, rt4, rt5, rt6, rt6, rt8, rt8, rt5, rt5, rt2, rt1, rt1, rt3, rt4, rt5];
            rt_matrix = rt_matrix + randn(1,length(rt_matrix))*noise*noiseSD;
            plot(app.signalUIAxes,(0:45*length(t)-1)*dt,rt_matrix);
 
            rt_1 = (rt4 + rt1) + randn(1,length(rt4 + rt1)) * noise * noiseSD;
            rt_2 = (rt4 + rt2) + randn(1,length(rt4 + rt2)) * noise * noiseSD;
            rt_3 = (rt4 + rt3) + randn(1,length(rt4 + rt3)) * noise * noiseSD;
            rt_4 = (rt4 + rt4) + randn(1,length(rt4 + rt4)) * noise * noiseSD;
            rt_5 = (rt4 + rt5) + randn(1,length(rt4 + rt5)) * noise * noiseSD;
            rt_6 = (rt4 + rt6) + randn(1,length(rt4 + rt6)) * noise * noiseSD;
            rt_7 = (rt4 + rt7) + randn(1,length(rt4 + rt7)) * noise * noiseSD;
            rt_8 = (rt4 + rt8) + randn(1,length(rt4 + rt8)) * noise * noiseSD;
            
            ct = length(rt)-N;
                for a = 0:ct
                    c_t4(a+1) = rt_4(a+1:a+N)*(st4+st4)';
                    c_t5(a+1) = rt_5(a+1:a+N)*(st5+st4)';
                    c_t6(a+1) = rt_6(a+1:a+N)*(st6+st4)';
                    c_t8(a+1) = rt_8(a+1:a+N)*(st8+st4)';
                    c_t1(a+1) = rt_1(a+1:a+N)*(st1+st4)';
                    c_t2(a+1) = rt_2(a+1:a+N)*(st2+st4)';
                    c_t3(a+1) = rt_3(a+1:a+N)*(st3+st4)';
                end
                total_c_t = [c_t4, c_t4, c_t5, c_t6, c_t6, c_t8, c_t8, c_t5, c_t5, c_t2, c_t1, c_t1, c_t3, c_t4, c_t5];

            plot(app.correlationUIAxes,(0:length(total_c_t)-1), abs(total_c_t));

            % 2차원 측면 input data, length : 30
            ground = [0 0 0 0 1 1 2 2 2 2 4 4 4 4 1 1 1 1 -2 -2 -3 -3 -3 -3 -1 -1 -1 0 0 1 1];
            x = 0 : pulsewidth : 300;
            plot(app.inputGround2dUIAxes,x,ground);
            xlabel(app.inputGround2dUIAxes,"distance[m]");
            ylabel(app.inputGround2dUIAxes,"height[m]");
            theta_ground = linspace(0, 2*pi, 1/2*app.BeamRangeEditField.Value);
            beamwidth_ground = 1/2*BeamWidth_xlength*cos(theta_ground);
            hold(app.inputGround2dUIAxes);
            plot(app.inputGround2dUIAxes,round(beamwidth_ground*10^-1),'r','LineWidth',2);
            legend(app.inputGround2dUIAxes,'input data','beam width');

            % 3차원 input data
            y = 0 : pulsewidth : 300;
            ground_3d = [ground].*[ground]';
            mesh(app.inputGround3dUIAxes,x,y,ground_3d);
            xlabel(app.inputGround3dUIAxes,"distance[m]");
            ylabel(app.inputGround3dUIAxes,"distance[m]");
            zlabel(app.inputGround3dUIAxes,"height[m]");
            hold(app.inputGround3dUIAxes);
            plot3(app.inputGround3dUIAxes,x_circle,y_circle,z_circle,'r','LineWidth',2);
            
            % output
            app.StandardTimeEditField.Value = (2*R*10^9)/c;
            app.DronetimeField.Value = 300/v;
            
            % 거리측정 
            new_ground = R + ground;                    % [m]
            time = (2*new_ground/c)*10^9;               % [ns]
            time_with_noise = time + 2*randn(1,length(x))*noise*noiseSD;
            R_with_noise = (time_with_noise*c/2)*10^-9;
            ground_with_noise = R_with_noise - R;
            final_ground = round(ground_with_noise);    % 최종 ground input data
            final_ground_3d = final_ground .* final_ground';
            
            plot(app.outputGround2dUIAxes,x,final_ground);
            mesh(app.outputGround3dUIAxes,x,y,final_ground_3d);           
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
% *********************************** Reset button *********************************
            % input parameter
            c = 3*10^8;
            app.PulsewidthEditField.Value = 10;
            pulsewidth = app.PulsewidthEditField.Value;
            dt = pulsewidth*10^-3;
            app.DroneHeightEditField.Value = 1;
            h = app.DroneHeightEditField.Value;
            app.DroneRadEditField.Value = 10;
            gamma = app.DroneRadEditField.Value;
            app.FcEditField.Value = 9;  % < L-band > frequency : 1~2[GHz], wavelength : 15~30[cm]
            fc = app.FcEditField.Value; % < X-band > frequency : 7.5~12[GHz], wavelength : 2.5~4[cm]
            app.DroneVEditField = 30;
            v = app.DroneVEditField.Value; 
            app.LEditField.Value = 1;
            L = app.LEditField.Value;
            app.WEditField.Value = 1;
            W = app.WEditField.Value;
            app.EfficiencyEditField.Value = 70;
            eta = app.EfficiencyEditField.Value;
            
            app.chirpgainEditField.Value = 1;
            chirp_gain = app.chirpgainEditField.Value;
            app.chirpfoEditField.Value = 1;
            chirp_fo = app.chirpfoEditField.Value;
            app.chirpmuEditField.Value = 1;
            chirp_mu = app.chirpmuEditField.Value;
            app.chirpdeltatauEditField.Value = 0.5;
            chirp_delta_tau = app.chirpdeltatauEditField.Value;
            
            % output parameter
            app.LambdaEditField.Value = c/(fc*10^9);
            lambda = app.LambdaEditField.Value;
            app.AeEditField.Value = L*W*0.01;
            Ae = app.AeEditField.Value;
            app.azEditField.Value = 0.886*(lambda/L)*10^2;
            theta_az = app.azEditField.Value;
            app.elEditField.Value = 0.886*(lambda/W)*10^2;
            theta_el = app.elEditField.Value;
            app.BandwidthEditField.Value = (1/pulsewidth)*10^3;
            Bandwidth = app.BandwidthEditField.Value;
            app.RangeResolutionEditField.Value = c/(2*Bandwidth*10^6);
            delta_r = app.RangeResolutionEditField.Value;
            app.AzimuthResolutionEditField.Value = L/2;
            delta_az = app.AzimuthResolutionEditField.Value;
            
            app.RangeEditField.Value = h/cos(deg2rad(gamma));
            R = app.RangeEditField.Value;
            app.PRFminEditField.Value = (2*v*sin(deg2rad(theta_az))*fc*10^9)/c;
            PRFmin = app.PRFminEditField.Value;
            app.PRIminEditField.Value = 1/PRFmin*10^3;
            PRImin = app.PRIminEditField.Value;
            app.GainEditField.Value = 4*pi*Ae*(eta*10^-2)/lambda^2;
            A = app.GainEditField.Value;
            app.GaindBEditField.Value = 10*log10(A);
            A_dB = app.GaindBEditField.Value;
            
            length_az = R/cos(deg2rad(theta_az));
            length_el = R/cos(deg2rad(theta_el));
            BeamWidth_xlength = 2*length_az*sin(deg2rad(theta_az));  % x축[m]
            BeamWidth_ylength = 2*length_el*sin(deg2rad(theta_el));  % y축[m]
            app.BeamRangeEditField.Value = BeamWidth_xlength*10^3;
            
            % Gausian Noise
            app.NoiseSwitchEditField.Value = 'Off';
            app.NoiseSwitchKnob.Value = 'Off';
            app.NoiseSDEditField.Value = 1;
            app.NoiseStandardDeviationKnob.Value = 1;
            noiseSD = app.NoiseSDEditField.Value;
            if isequal(app.NoiseSwitchKnob.Value,'Off')
                noise = 0;
            elseif isequal(app.NoiseSwitchKnob.Value,'On')
                noise = 1;
            end
            
            % 지표에 비치는 빔의 길이
            theta = linspace(0, 2*pi, 100);
            x_circle = 1/2*BeamWidth_xlength*10^3*cos(theta);
            y_circle = 1/2*BeamWidth_ylength*10^3*sin(theta);
            z_circle = ones(1,100);
            plot(app.beamUIAxes,x_circle,y_circle);
            axis(app.beamUIAxes,'square');
            
            % signal
            t = 0 : dt : pulsewidth;      % 0 : 0.01 : 10 [ns]
            N = length(t);
            st1 = chirp_gain.*cos(2*pi.*(1*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st2 = chirp_gain.*cos(2*pi.*(2*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st3 = chirp_gain.*cos(2*pi.*(3*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st4 = chirp_gain.*cos(2*pi.*(chirp_fo*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st5 = chirp_gain.*cos(2*pi.*(5*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st6 = chirp_gain.*cos(2*pi.*(6*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st7 = chirp_gain.*cos(2*pi.*(7*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            st8 = chirp_gain.*cos(2*pi.*(8*(t - chirp_delta_tau) + (chirp_mu/2).*(t - chirp_delta_tau).^2));
            
            rt1 = [ zeros(1,N), st1, zeros(1,N) ];
            rt2 = [ zeros(1,N+10), st2, zeros(1,N-10) ];
            rt3 = [ zeros(1,N+20), st3, zeros(1,N-20) ];
            rt4 = [ zeros(1,N+30), st4, zeros(1,N-30) ];
            rt5 = [ zeros(1,N+40), st5, zeros(1,N-40) ];
            rt6 = [ zeros(1,N+50), st6, zeros(1,N-50) ];
            rt7 = [ zeros(1,N+60), st7, zeros(1,N-60) ];
            rt8 = [ zeros(1,N+70), st8, zeros(1,N-70) ];
            
            rt = (rt4+rt4+rt5+rt6+rt6+rt8+rt8+rt5+rt5+rt2+rt1+rt1+rt3+rt4+rt5)+randn(1,length(rt4+rt4+rt5+rt6+rt6+rt8+rt8+rt5+rt5+rt2+rt1+rt1+rt3+rt4+rt5))*noise*noiseSD;
            rt_matrix = [rt4, rt4, rt5, rt6, rt6, rt8, rt8, rt5, rt5, rt2, rt1, rt1, rt3, rt4, rt5];
            rt_matrix = rt_matrix + randn(1,length(rt_matrix))*noise*noiseSD;
            plot(app.signalUIAxes,(0:45*length(t)-1)*dt,rt_matrix);
 
            rt_1 = (rt4 + rt1) + randn(1,length(rt4 + rt1)) * noise * noiseSD;
            rt_2 = (rt4 + rt2) + randn(1,length(rt4 + rt2)) * noise * noiseSD;
            rt_3 = (rt4 + rt3) + randn(1,length(rt4 + rt3)) * noise * noiseSD;
            rt_4 = (rt4 + rt4) + randn(1,length(rt4 + rt4)) * noise * noiseSD;
            rt_5 = (rt4 + rt5) + randn(1,length(rt4 + rt5)) * noise * noiseSD;
            rt_6 = (rt4 + rt6) + randn(1,length(rt4 + rt6)) * noise * noiseSD;
            rt_7 = (rt4 + rt7) + randn(1,length(rt4 + rt7)) * noise * noiseSD;
            rt_8 = (rt4 + rt8) + randn(1,length(rt4 + rt8)) * noise * noiseSD;
            
            ct = length(rt)-N;
                for a = 0:ct
                    c_t4(a+1) = rt_4(a+1:a+N)*(st4+st4)';
                    c_t5(a+1) = rt_5(a+1:a+N)*(st5+st4)';
                    c_t6(a+1) = rt_6(a+1:a+N)*(st6+st4)';
                    c_t8(a+1) = rt_8(a+1:a+N)*(st8+st4)';
                    c_t1(a+1) = rt_1(a+1:a+N)*(st1+st4)';
                    c_t2(a+1) = rt_2(a+1:a+N)*(st2+st4)';
                    c_t3(a+1) = rt_3(a+1:a+N)*(st3+st4)';
                end
                total_c_t = [c_t4, c_t4, c_t5, c_t6, c_t6, c_t8, c_t8, c_t5, c_t5, c_t2, c_t1, c_t1, c_t3, c_t4, c_t5];

            plot(app.correlationUIAxes,(0:length(total_c_t)-1), abs(total_c_t));

            % 2차원 측면 input data, length : 30
            ground = [0 0 0 0 1 1 2 2 2 2 4 4 4 4 1 1 1 1 -2 -2 -3 -3 -3 -3 -1 -1 -1 0 0 1 1];
            x = 0 : pulsewidth : 300;
            plot(app.inputGround2dUIAxes,x,ground);
            xlabel(app.inputGround2dUIAxes,"distance[m]");
            ylabel(app.inputGround2dUIAxes,"height[m]");
            theta_ground = linspace(0, 2*pi, 1/2*app.BeamRangeEditField.Value);
            beamwidth_ground = 1/2*BeamWidth_xlength*cos(theta_ground);
            hold(app.inputGround2dUIAxes);
            plot(app.inputGround2dUIAxes,round(beamwidth_ground*10^-1),'r','LineWidth',2);
            legend(app.inputGround2dUIAxes,'input data','beam width');
            
            % 3차원 input data
            y = 0 : pulsewidth : 300;
            ground_3d = [ground].*[ground]';
            mesh(app.inputGround3dUIAxes,x,y,ground_3d);
            xlabel(app.inputGround3dUIAxes,"distance[m]");
            ylabel(app.inputGround3dUIAxes,"distance[m]");
            zlabel(app.inputGround3dUIAxes,"height[m]");
            hold(app.inputGround3dUIAxes);
            plot3(app.inputGround3dUIAxes,x_circle,y_circle,z_circle,'r','LineWidth',2);
            
            % output
            app.StandardTimeEditField.Value = (2*R*10^9)/c;
            app.DronetimeField.Value = 300/v;
            
            % 거리측정 
            new_ground = R + ground;                    % [m]
            time = (2*new_ground/c)*10^9;               % [ns]
            time_with_noise = time + 2*randn(1,length(x))*noise*noiseSD;
            R_with_noise = (time_with_noise*c/2)*10^-9;
            ground_with_noise = R_with_noise - R;
            final_ground = round(ground_with_noise);    % 최종 ground input data
            final_ground_3d = final_ground .* final_ground';
            
            plot(app.outputGround2dUIAxes,x,final_ground);
            mesh(app.outputGround3dUIAxes,x,y,final_ground_3d);
        end

        % Value changed function: NoiseSwitchKnob
        function NoiseSwitchKnobValueChanged(app, event)
            value = app.NoiseSwitchKnob.Value;
            app.NoiseSwitchEditField.Value = value;
        end

        % Value changed function: NoiseSwitchEditField
        function NoiseSwitchEditFieldValueChanged(app, event)
            value = app.NoiseSwitchEditField.Value;
            app.NoiseSwitchKnob.Value = value;
        end

        % Value changed function: NoiseStandardDeviationKnob
        function NoiseStandardDeviationKnobValueChanged(app, event)
            value = app.NoiseStandardDeviationKnob.Value;
            app.NoiseSDEditField.Value = value;
        end

        % Value changed function: NoiseSDEditField
        function NoiseSDEditFieldValueChanged(app, event)
            value = app.NoiseSDEditField.Value;
            app.NoiseStandardDeviationKnob.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.InputParameterPanel = uipanel(app.UIFigure);
            app.InputParameterPanel.Title = 'Input Parameter';
            app.PulsewidthEditFieldLabel = uilabel(app.InputParameterPanel);
            app.PulsewidthEditFieldLabel.Text = 'Pulsewidth';
            app.PulsewidthEditField = uieditfield(app.InputParameterPanel, 'numeric');
            app.PulsewidthEditField.Value = 10;
            app.Label = uilabel(app.InputParameterPanel);
            app.Label.Text = '탑재체 운용고도';
            app.DroneHeightEditField = uieditfield(app.InputParameterPanel, 'numeric');
            app.DroneHeightEditField.Value = 1;
            app.Label_2 = uilabel(app.InputParameterPanel);
            app.Label_2.Text = '관측각도';
            app.DroneRadEditField = uieditfield(app.InputParameterPanel, 'numeric');
            app.DroneRadEditField.Value = 10;
            app.nsLabel = uilabel(app.InputParameterPanel);
            app.nsLabel.Text = '[ns]';
            app.kmLabel = uilabel(app.InputParameterPanel);
            app.kmLabel.Text = '[km]';
            app.radLabel = uilabel(app.InputParameterPanel);
            app.radLabel.Text = '[ °]';
            app.GHzLabel = uilabel(app.InputParameterPanel);
            app.GHzLabel.Text = '[GHz]';
            app.Label_4 = uilabel(app.InputParameterPanel);
            app.Label_4.Text = '운용주파수';
            app.FcEditField = uieditfield(app.InputParameterPanel, 'numeric');
            app.FcEditField.Value = 9;
            app.Label_5 = uilabel(app.InputParameterPanel);
            app.Label_5.Text = '탑재체 속도';
            app.DroneVEditField = uieditfield(app.InputParameterPanel, 'numeric');
            app.DroneVEditField.Value = 30;
            app.msLabel = uilabel(app.InputParameterPanel);
            app.msLabel.Text = '[m/s]';
            app.LLabel = uilabel(app.InputParameterPanel);
            app.LLabel.Text = '안테나 길이(L)';
            app.LEditField = uieditfield(app.InputParameterPanel, 'numeric');
            app.LEditField.Value = 1;
            app.WEditFieldLabel = uilabel(app.InputParameterPanel);
            app.WEditFieldLabel.Text = '안테나 폭(W)';
            app.WEditField = uieditfield(app.InputParameterPanel, 'numeric');
            app.WEditField.Value = 1;
            app.Label_6 = uilabel(app.InputParameterPanel);
            app.Label_6.Text = '안테나 개구효율';
            app.EfficiencyEditField = uieditfield(app.InputParameterPanel, 'numeric');
            app.EfficiencyEditField.Value = 70;
            app.mazimuthLabel = uilabel(app.InputParameterPanel);
            app.mazimuthLabel.Text = '[m], azimuth방향';
            app.melevationLabel = uilabel(app.InputParameterPanel);
            app.melevationLabel.Text = '[m], elevation방향';
            app.Label_7 = uilabel(app.InputParameterPanel);
            app.Label_7.Text = '[%]';
            app.chirpgainEditFieldLabel = uilabel(app.InputParameterPanel);
            app.chirpgainEditFieldLabel.Text = 'chirp gain';
            app.chirpgainEditField = uieditfield(app.InputParameterPanel, 'numeric');
            app.chirpgainEditField.Value = 1;
            app.chirpfoEditFieldLabel = uilabel(app.InputParameterPanel);
            app.chirpfoEditFieldLabel.Text = 'chirp fo';
            app.chirpfoEditField = uieditfield(app.InputParameterPanel, 'numeric');
            app.chirpfoEditField.Value = 1;
            app.chirpdeltatauEditFieldLabel = uilabel(app.InputParameterPanel);
            app.chirpdeltatauEditFieldLabel.Text = 'chirp delta tau';
            app.chirpdeltatauEditField = uieditfield(app.InputParameterPanel, 'numeric');
            app.chirpdeltatauEditField.Value = 0.5;
            app.chirpmuEditFieldLabel = uilabel(app.InputParameterPanel);
            app.chirpmuEditFieldLabel.Text = 'chirp mu';
            app.chirpmuEditField = uieditfield(app.InputParameterPanel, 'numeric');
            app.chirpmuEditField.Value = 1;
            app.nsLabel_2 = uilabel(app.InputParameterPanel);
            app.nsLabel_2.Text = '[ns]';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.OutputParameterPanel = uipanel(app.UIFigure);
            app.OutputParameterPanel.Title = 'Output Parameter';
            app.mLabel_5 = uilabel(app.OutputParameterPanel);
            app.mLabel_5.Text = '[m]';
            app.m2Label = uilabel(app.OutputParameterPanel);
            app.m2Label.Text = '[m^2]';
            app.Label_8 = uilabel(app.OutputParameterPanel);
            app.Label_8.Text = '[ °]';
            app.Label_9 = uilabel(app.OutputParameterPanel);
            app.Label_9.Text = '[ °]';
            app.dBLabel = uilabel(app.OutputParameterPanel);
            app.dBLabel.Text = '[dB]';
            app.MHzLabel = uilabel(app.OutputParameterPanel);
            app.MHzLabel.Text = '[MHz]';
            app.mLabel = uilabel(app.OutputParameterPanel);
            app.mLabel.Text = '[m]';
            app.mLabel_2 = uilabel(app.OutputParameterPanel);
            app.mLabel_2.Text = '[m]';
            app.kmLabel_2 = uilabel(app.OutputParameterPanel);
            app.kmLabel_2.Text = '[km]';
            app.EditField_4Label = uilabel(app.OutputParameterPanel);
            app.EditField_4Label.Text = '파장';
            app.LambdaEditField = uieditfield(app.OutputParameterPanel, 'numeric');
            app.LambdaEditField.Value = 0.03333;
            app.EditFieldLabel = uilabel(app.OutputParameterPanel);
            app.EditFieldLabel.Text = '안테나 유효면적';
            app.AeEditField = uieditfield(app.OutputParameterPanel, 'numeric');
            app.AeEditField.Value = 0.01;
            app.azEditFieldLabel = uilabel(app.OutputParameterPanel);
            app.azEditFieldLabel.Text = '안테나 빔폭(az)';
            app.azEditField = uieditfield(app.OutputParameterPanel, 'numeric');
            app.azEditField.Value = 2.953;
            app.elEditFieldLabel = uilabel(app.OutputParameterPanel);
            app.elEditFieldLabel.Text = '안테나 빔폭(el)';
            app.elEditField = uieditfield(app.OutputParameterPanel, 'numeric');
            app.elEditField.Value = 2.953;
            app.Label_10 = uilabel(app.OutputParameterPanel);
            app.Label_10.Text = '안테나 이득';
            app.GainEditField = uieditfield(app.OutputParameterPanel, 'numeric');
            app.GainEditField.Value = 79.17;
            app.EditField_4Label_2 = uilabel(app.OutputParameterPanel);
            app.EditField_4Label_2.Text = '안테나 이득';
            app.GaindBEditField = uieditfield(app.OutputParameterPanel, 'numeric');
            app.GaindBEditField.Value = 18.99;
            app.BandwidthEditFieldLabel = uilabel(app.OutputParameterPanel);
            app.BandwidthEditFieldLabel.Text = 'Bandwidth';
            app.BandwidthEditField = uieditfield(app.OutputParameterPanel, 'numeric');
            app.BandwidthEditField.Value = 100;
            app.Label_11 = uilabel(app.OutputParameterPanel);
            app.Label_11.Text = '거리분해능';
            app.RangeResolutionEditField = uieditfield(app.OutputParameterPanel, 'numeric');
            app.RangeResolutionEditField.Value = 1.5;
            app.EditField_6Label = uilabel(app.OutputParameterPanel);
            app.EditField_6Label.Text = '방위분해능';
            app.AzimuthResolutionEditField = uieditfield(app.OutputParameterPanel, 'numeric');
            app.AzimuthResolutionEditField.Value = 0.5;
            app.Label_12 = uilabel(app.OutputParameterPanel);
            app.Label_12.Text = '지표까지의 거리';
            app.RangeEditField = uieditfield(app.OutputParameterPanel, 'numeric');
            app.RangeEditField.Value = 1.015;
            app.PRIminEditFieldLabel = uilabel(app.OutputParameterPanel);
            app.PRIminEditFieldLabel.Text = 'PRImin';
            app.PRIminEditField = uieditfield(app.OutputParameterPanel, 'numeric');
            app.PRIminEditField.Value = 10.78;
            app.PRFminEditFieldLabel = uilabel(app.OutputParameterPanel);
            app.PRFminEditFieldLabel.Text = 'PRFmin';
            app.PRFminEditField = uieditfield(app.OutputParameterPanel, 'numeric');
            app.PRFminEditField.Value = 92.74;
            app.Label_13 = uilabel(app.OutputParameterPanel);
            app.Label_13.Text = '빔의 길이';
            app.BeamRangeEditField = uieditfield(app.OutputParameterPanel, 'numeric');
            app.BeamRangeEditField.Value = 104.8;
            app.mLabel_4 = uilabel(app.OutputParameterPanel);
            app.mLabel_4.Text = '[m]';
            app.msLabel_2 = uilabel(app.OutputParameterPanel);
            app.msLabel_2.Text = '[ms]';
            app.HzLabel = uilabel(app.OutputParameterPanel);
            app.HzLabel.Text = '[Hz]';
            app.beamUIAxes = uiaxes(app.UIFigure);
            title(app.beamUIAxes, '지표에 비치는 빔의 범위')
            xlabel(app.beamUIAxes, 'distance [m]')
            ylabel(app.beamUIAxes, 'distance [m]')
            app.correlationUIAxes = uiaxes(app.UIFigure);
            title(app.correlationUIAxes, 'correlation processor')
            xlabel(app.correlationUIAxes, 'time [ns]')
            ylabel(app.correlationUIAxes, '')
            app.inputGround2dUIAxes = uiaxes(app.UIFigure);
            title(app.inputGround2dUIAxes, 'input data 2D')
            xlabel(app.inputGround2dUIAxes, 'distance [m]')
            ylabel(app.inputGround2dUIAxes, 'height [m]')
            app.inputGround3dUIAxes = uiaxes(app.UIFigure);
            title(app.inputGround3dUIAxes, 'input data 3D')
            xlabel(app.inputGround3dUIAxes, 'distance [m]')
            ylabel(app.inputGround3dUIAxes, 'distance [m]')
            app.outputGround2dUIAxes = uiaxes(app.UIFigure);
            title(app.outputGround2dUIAxes, 'output data 2D')
            xlabel(app.outputGround2dUIAxes, 'distance [m]')
            ylabel(app.outputGround2dUIAxes, 'height [m]')
            app.outputGround3dUIAxes = uiaxes(app.UIFigure);
            title(app.outputGround3dUIAxes, 'output data 3D')
            xlabel(app.outputGround3dUIAxes, 'distance [m]')
            ylabel(app.outputGround3dUIAxes, 'distance [m]')
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.Label_15 = uilabel(app.OutputPanel);
            app.Label_15.Text = '신호의 수신까지 걸린 시간';
            app.StandardTimeEditField = uieditfield(app.OutputPanel, 'numeric');
            app.StandardTimeEditField.Value = 6.77;
            app.usLabel = uilabel(app.OutputPanel);
            app.usLabel.Text = '[us]';
            app.Label_14 = uilabel(app.OutputPanel);
            app.Label_14.Text = '[%]';
            app.ErrorrateEditFieldLabel = uilabel(app.OutputPanel);
            app.ErrorrateEditFieldLabel.Text = 'Error rate';
            app.ErrorrateEditField = uieditfield(app.OutputPanel, 'numeric');
            app.Label_16 = uilabel(app.OutputPanel);
            app.Label_16.Text = '이동하는 데 걸린 시간';
            app.DronetimeField = uieditfield(app.OutputPanel, 'numeric');
            app.DronetimeField.Value = 10;
            app.sLabel = uilabel(app.OutputPanel);
            app.sLabel.Text = '[s]';
            app.signalUIAxes = uiaxes(app.UIFigure);
            title(app.signalUIAxes, 'received signal')
            xlabel(app.signalUIAxes, 'time [ns]')
            ylabel(app.signalUIAxes, '')
            app.GaussianNoisePanel = uipanel(app.UIFigure);
            app.GaussianNoisePanel.Title = 'Gaussian Noise';
            app.NoiseSDEditField = uieditfield(app.GaussianNoisePanel, 'numeric');
            app.NoiseSDEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseSDEditFieldValueChanged, true);
            app.NoiseSDEditField.Value = 1;
            app.SwitchKnobLabel = uilabel(app.GaussianNoisePanel);
            app.SwitchKnobLabel.Text = 'Switch';
            app.NoiseSwitchKnob = uiknob(app.GaussianNoisePanel, 'discrete');
            app.NoiseSwitchKnob.Items = {'Off', 'On'};
            app.NoiseSwitchKnob.ValueChangedFcn = createCallbackFcn(app, @NoiseSwitchKnobValueChanged, true);
            app.StandardDeviationKnobLabel = uilabel(app.GaussianNoisePanel);
            app.StandardDeviationKnobLabel.Text = 'Standard Deviation';
            app.NoiseStandardDeviationKnob = uiknob(app.GaussianNoisePanel, 'continuous');
            app.NoiseStandardDeviationKnob.Limits = [0 10];
            app.NoiseStandardDeviationKnob.ValueChangedFcn = createCallbackFcn(app, @NoiseStandardDeviationKnobValueChanged, true);
            app.NoiseStandardDeviationKnob.Value = 1;
            app.NoiseSwitchEditField = uieditfield(app.GaussianNoisePanel, 'text');
            app.NoiseSwitchEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseSwitchEditFieldValueChanged, true);
            app.NoiseSwitchEditField.Value = 'Off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SAR0105

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end